classdef app_results_selector < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        title                    matlab.ui.control.Label
        TestDropDown             matlab.ui.control.DropDown
        ControllerDropDown       matlab.ui.control.DropDown
        FilterDropDown           matlab.ui.control.DropDown
        ExportButton             matlab.ui.control.Button
        PlotButton               matlab.ui.control.Button
        FilterDropDownLabel      matlab.ui.control.Label
        ControllerDropDownLabel  matlab.ui.control.Label
        TestDropDownLabel        matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, test_labels, controller_labels, filter_labels)
            app.TestDropDown.Items = test_labels;
            app.ControllerDropDown.Items = controller_labels;
            app.FilterDropDown.Items = filter_labels;
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            simouts = fetch_results(app.TestDropDown.Value, app.ControllerDropDown.Value, app.FilterDropDown.Value);
            if isempty(simouts)
                uialert(app.UIFigure,"No result found!", "Warning", "Icon", "warning");
                return
            end
            plot_results(simouts);
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            testFileName = app.TestDropDown.Value;
            controllerFileName = replace(app.ControllerDropDown.Value, '*', 'star');
            filterDropDownFileName = replace(app.FilterDropDown.Value, '*', 'star');
            
            filename = [testFileName '_' controllerFileName '_' filterDropDownFileName];

            export_pdf_graphic(gcf, ['/results/' filename]);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TestDropDownLabel = uilabel(app.GridLayout);
            app.TestDropDownLabel.Text = 'Test';
            app.ControllerDropDownLabel = uilabel(app.GridLayout);
            app.ControllerDropDownLabel.Text = 'Controller';
            app.FilterDropDownLabel = uilabel(app.GridLayout);
            app.FilterDropDownLabel.Text = 'Filter';
            app.PlotButton = uibutton(app.GridLayout, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.ExportButton = uibutton(app.GridLayout, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.FilterDropDown = uidropdown(app.GridLayout);
            app.ControllerDropDown = uidropdown(app.GridLayout);
            app.TestDropDown = uidropdown(app.GridLayout);
            app.title = uilabel(app.GridLayout);
            app.title.Text = 'MagLev results selector';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_results_selector(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end